classdef front_end < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        Tab                   matlab.ui.container.Tab
        Image                 matlab.ui.control.Image
        SignUpLabel           matlab.ui.control.Label
        HeightEditField       matlab.ui.control.EditField
        HeightEditFieldLabel  matlab.ui.control.Label
        WeightEditField       matlab.ui.control.EditField
        WeightEditFieldLabel  matlab.ui.control.Label
        GenderDropDown        matlab.ui.control.DropDown
        GenderDropDownLabel   matlab.ui.control.Label
        AgeEditField          matlab.ui.control.EditField
        AgeEditFieldLabel     matlab.ui.control.Label
        NameEditField         matlab.ui.control.EditField
        NameEditFieldLabel    matlab.ui.control.Label
        SubmitButton          matlab.ui.control.Button
        Tab2                  matlab.ui.container.Tab
        OutputTextArea        matlab.ui.control.TextArea
        OutputTextAreaLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
         name
         age
         gender
         weight
         height
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            disp('Submit button pressed');
            app.weight = app.WeightEditField.Value;  % Correct property usage
            disp(['User input: ', app.weight]);  % Display the input for debugging
            app.OutputTextArea.Value = {['You entered: ', app.weight, app.height]};  % Update text area
            app.WeightEditField.Value = '';  % Correct property usage
            
            app.height = app.HeightEditField.Value;  % Correct property usage
            disp(['User input: ', app.height]);  % Display the input for debugging
           
            app.HeightEditField.Value = '';  % Correct property usage
            app.TabGroup.SelectedTab = app.Tab2;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.SubmitButton = uibutton(app.Tab, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.NameEditFieldLabel = uilabel(app.Tab);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Tab, 'text');
            app.NameEditField.Placeholder = 'Enter Your Name';
            app.AgeEditFieldLabel = uilabel(app.Tab);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.Tab, 'text');
            app.AgeEditField.Placeholder = 'Enter Your Age';
            app.GenderDropDownLabel = uilabel(app.Tab);
            app.GenderDropDownLabel.Text = 'Gender';
            app.GenderDropDown = uidropdown(app.Tab);
            app.GenderDropDown.Items = {'Select Your Gender', 'Female', 'Male', 'Binary', 'Prefer not to say'};
            app.GenderDropDown.Value = 'Select Your Gender';
            app.WeightEditFieldLabel = uilabel(app.Tab);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.WeightEditField = uieditfield(app.Tab, 'text');
            app.WeightEditField.Placeholder = 'Enter Your Weight';
            app.HeightEditFieldLabel = uilabel(app.Tab);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.Tab, 'text');
            app.HeightEditField.Placeholder = 'Enter Your Height';
            app.SignUpLabel = uilabel(app.Tab);
            app.SignUpLabel.Text = 'Sign Up!';
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'image_123650291.JPG');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.OutputTextAreaLabel = uilabel(app.Tab2);
            app.OutputTextAreaLabel.Text = 'OutputTextArea';
            app.OutputTextArea = uitextarea(app.Tab2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = front_end

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end